classdef Exoskeleton_User_Interface_32data_Junxi_20220812 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExoskeletonAppUIFigure         matlab.ui.Figure
        TaskDropDown                   matlab.ui.control.DropDown
        TaskDropDownLabel              matlab.ui.control.Label
        StartLoggingButton             matlab.ui.control.StateButton
        TrialSpinner                   matlab.ui.control.Spinner
        TrialSpinnerLabel              matlab.ui.control.Label
        ConditionDropDown              matlab.ui.control.DropDown
        ConditionDropDownLabel         matlab.ui.control.Label
        RESETButton                    matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        ParametersTab                  matlab.ui.container.Tab
        CommonGainSpinner              matlab.ui.control.Spinner
        CommonGainSpinnerLabel         matlab.ui.control.Label
        RightGainSpinner               matlab.ui.control.Spinner
        RightGainSpinnerLabel          matlab.ui.control.Label
        LeftGainSpinner                matlab.ui.control.Spinner
        LeftGainSpinnerLabel           matlab.ui.control.Label
        VelocityThresholdSpinner       matlab.ui.control.Spinner
        VelocityThresholdSpinnerLabel  matlab.ui.control.Label
        AngleThresholdSpinner          matlab.ui.control.Spinner
        AngleThresholdSpinnerLabel     matlab.ui.control.Label
        CONTROLLabel                   matlab.ui.control.Label
        USERLabel                      matlab.ui.control.Label
        SexButtonGroup                 matlab.ui.container.ButtonGroup
        FButton                        matlab.ui.control.RadioButton
        MButton                        matlab.ui.control.RadioButton
        HeightmEditField               matlab.ui.control.NumericEditField
        HeightmEditFieldLabel          matlab.ui.control.Label
        WeightkgEditField              matlab.ui.control.NumericEditField
        WeightkgEditFieldLabel         matlab.ui.control.Label
        STOPButton                     matlab.ui.control.StateButton
        TimeLabel                      matlab.ui.control.Label
        DateLabel                      matlab.ui.control.Label
        SaveDataButton                 matlab.ui.control.Button
        PortDropDown                   matlab.ui.control.DropDown
        PortDropDownLabel              matlab.ui.control.Label
        BaudRateDropDown               matlab.ui.control.DropDown
        BaudRateDropDownLabel          matlab.ui.control.Label
        ConnectButton                  matlab.ui.control.Button
        ModeDropDown                   matlab.ui.control.DropDown
        ModeDropDownLabel              matlab.ui.control.Label
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_1                       matlab.ui.control.UIAxes
        UIAxes_4                       matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
    end


    properties (Access = public)
        serialConn;
        %length=100;
        timeSet;
        
        angleL_Set;
        angleR_Set;
        current_command_L_Set;
        current_command_R_Set;
        current_L_Set;
        current_R_Set;
        torque_command_L_Set;
        torque_command_R_Set;
        torque_L_Set;
        torque_R_Set;
        lMotorAngle_Set;
        rMotorAngle_Set;
        lMotorSpeed_Set;
        rMotorSpeed_Set;
        
        %trigger_Set;
        %percentageSet;
        %currentSet;
        %LTAVSet;
        %RTAVSet;
        %LSAVSet;
        %RSAVSet;
        %LTSet;
        %RTSet;
        %torque_command_GPSet;
        %isStandSet;


        time=0;
        sample_time=0.01;
        %triggerVal;

        h1;
        h2;
        h3;
        h4;
        h5;
        h6;
        ploti=0;
        ble_datalength=32;
        rs232_datalength=20;

        wsindex=1;
        mat_save_interval=200;
        mat_ini=1;
        mat_end=200;
        m;
        isSaveData=0;
        num_variables=21;
        flag=0;
    end
    methods (Access = private)

        function serial_data_available(app,src,event)
            %app.DateLabel.Text=fgetl(app.serialConn);
            %RxText = fscanf(app.serialConn);
            if(read(app.serialConn,1,"uint8")==165)
                if(read(app.serialConn,1,"uint8")==90)
                    %length=read(app.serialConn,1,"uint8");
                    if(read(app.serialConn,1,"uint8")==app.ble_datalength)
                        c=read(app.serialConn,app.ble_datalength-3,"uint8");

                        angleL=c(1)+c(2)*256;
                        if(angleL>32767)
                            angleL=angleL-65536;
                        end
                        angleL=angleL/100;

                        angleR=c(3)+c(4)*256;
                        if(angleR>32767)
                            angleR=angleR-65536;
                        end
                        angleR=angleR/100;

                        current_command_L=c(5)+c(6)*256;
                        if(current_command_L>32767)
                            current_command_L=current_command_L-65536;
                        end
                        current_command_L=current_command_L/100;

                        current_command_R=c(7)+c(8)*256;
                        if(current_command_R>32767)
                            current_command_R=current_command_R-65536;
                        end
                        current_command_R=current_command_R/100;

                        current_L=c(9)+c(10)*256;
                        if(current_L>32767)
                            current_L=current_L-65536;
                        end
                        current_L=current_L/100;

                        current_R=c(11)+c(12)*256;
                        if(current_R>32767)
                            current_R=current_R-65536;
                        end
                        current_R=current_R/100;

                        torque_command_L=c(13)+c(14)*256;
                        if(torque_command_L>32767)
                            torque_command_L=torque_command_L-65536;
                        end
                        torque_command_L=torque_command_L/100;

                        torque_command_R=c(15)+c(16)*256;
                        if(torque_command_R>32767)
                            torque_command_R=torque_command_R-65536;
                        end
                        torque_command_R=torque_command_R/100;

                        torque_L=c(17)+c(18)*256;
                        if(torque_L>32767)
                            torque_L=torque_L-65536;
                        end
                        torque_L=torque_L/100; % actuator output torque

                        torque_R=c(19)+c(20)*256;
                        if(torque_R>32767)
                            torque_R=torque_R-65536;
                        end
                        torque_R=torque_R/100; % actuator output torque

                        lMotorAngle=c(21)+c(22)*256; 
                        if(lMotorAngle>32767)
                            lMotorAngle=lMotorAngle-65536;
                        end
                        lMotorAngle=lMotorAngle/100; % radian

                        rMotorAngle=c(23)+c(24)*256;
                        if(rMotorAngle>32767)
                            rMotorAngle=rMotorAngle-65536;
                        end
                        rMotorAngle=rMotorAngle/100; % radian

                        lMotorSpeed=c(25)+c(26)*256; 
                        if(lMotorSpeed>32767)
                            lMotorSpeed=lMotorSpeed-65536;
                        end
                        lMotorSpeed=lMotorSpeed/100; % radian

                        rMotorSpeed=c(27)+c(28)*256;
                        if(rMotorSpeed>32767)
                            rMotorSpeed=rMotorSpeed-65536;
                        end
                        rMotorSpeed=rMotorSpeed/100; % radian

                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                        app.DateLabel.Text=num2str(app.wsindex);
                        %app.TimeLabel.Text=num2str(triggerOn);
                        app.time=app.time+app.sample_time;
                        %app.timeSet=[app.timeSet(2:end,:);app.time]
                        app.angleL_Set=[app.angleL_Set(2:end);angleL];
                        app.angleR_Set=[app.angleR_Set(2:end);angleR];
                        app.current_command_L_Set=[app.current_command_L_Set(2:end); current_command_L];
                        app.current_command_R_Set=[app.current_command_R_Set(2:end); current_command_R];
                        app.current_L_Set=[app.current_L_Set(2:end); current_L];
                        app.current_R_Set=[app.current_R_Set(2:end); current_R];
                        app.torque_command_L_Set=[app.torque_command_L_Set(2:end); torque_command_L];
                        app.torque_command_R_Set=[app.torque_command_R_Set(2:end); torque_command_R];
                        app.torque_L_Set=[app.torque_L_Set(2:end); torque_L];
                        app.torque_R_Set=[app.torque_R_Set(2:end); torque_R];
                        app.lMotorAngle_Set=[app.lMotorAngle_Set(2:end); lMotorAngle];
                        app.rMotorAngle_Set=[app.rMotorAngle_Set(2:end); rMotorAngle];
                        app.lMotorSpeed_Set=[app.lMotorSpeed_Set(2:end); lMotorSpeed];
                        app.rMotorSpeed_Set=[app.rMotorSpeed_Set(2:end); rMotorSpeed];
                        %app.trigger_Set = [app.trigger_Set(2:end); triggerOn];                                               

                        app.wsindex=app.wsindex+1;
                        if app.wsindex>=app.mat_save_interval
                            if(app.isSaveData ==1)
                                %assignin('base', 'NameOfNewVariable', [app.LKSet app.RKSet app.percentageSet app.torque_commandSet app.torqueSet app.current_commandSet app.currentSet])
                                app.m.data(app.mat_ini:app.mat_end,1:app.num_variables) = ...
                                    [app.angleL_Set app.angleL_Set ...
                                    app.current_command_L_Set app.current_command_R_Set ...
                                    app.current_L_Set app.current_R_Set ...
                                    app.torque_command_L_Set app.torque_command_R_Set ...
                                    app.torque_L_Set app.torque_R_Set ...
                                    app.lMotorAngle_Set app.rMotorAngle_Set ...
                                    app.lMotorSpeed_Set app.rMotorSpeed_Set];
                                    %app.trigger_Set...
                                    %];
                                %app.LTSet app.RTSet ...
                                %app.LTAVSet app.RTAVSet ...
                                %app.LSAVSet app.RSAVSet ...
                                %app.percentageSet ...
                                %app.torque_commandSet app.torqueSet ...
                                %app.current_commandSet app.currentSet...
                                %app.torque_command_GPSet ...
                                %app.isStandSet];
                                app.mat_ini=app.mat_ini+app.mat_save_interval;
                                app.mat_end=app.mat_end+app.mat_save_interval;
                                %evalin('base', 'data=[]');
                            end
                            app.wsindex=1;
                        end

                        app.ploti=app.ploti+1;
                        if(app.ploti>=50)
                            app.ploti=0;
                            %app.UIAxes_1.XLim=[app.timeSet(1) app.timeSet(end,1)];
                            %app.UIAxes_2.XLim=[app.timeSet(1) app.timeSet(end,1)];
                            %app.UIAxes_3.XLim=[app.timeSet(1) app.timeSet(end,1)];
                            %app.UIAxes_4.XLim=[app.timeSet(1) app.timeSet(end,1)];
                            set(app.h1(1),'xdata',app.timeSet,'ydata',app.angleL_Set);
                            set(app.h1(2),'xdata',app.timeSet,'ydata',app.angleR_Set);
                            set(app.h2(1),'xdata',app.timeSet,'ydata',app.current_command_L_Set);
                            set(app.h2(2),'xdata',app.timeSet,'ydata',app.current_command_R_Set);
                            set(app.h3(1),'xdata',app.timeSet,'ydata',app.torque_command_L_Set);
                            set(app.h3(2),'xdata',app.timeSet,'ydata',app.torque_L_Set);
                            set(app.h4(1),'xdata',app.timeSet,'ydata',app.torque_command_R_Set);
                            set(app.h4(2),'xdata',app.timeSet,'ydata',app.torque_R_Set);
%                             set(app.h4(2),'xdata',app.timeSet,'ydata',app.torque_R_Set);
%                             set(app.h5(1),'xdata',[0.5, 0.5+0.25*sin(RK*pi/180), 0.5],'ydata',[0.6, 0.6-0.25*cos(RK*pi/180), 0.6-2*0.25*cos(RK*pi/180)],'Color',[0.8500 0.3250 0.0980]);% red for right
%                             set(app.h5(2),'xdata',[0.5, 0.5+0.25*sin(LK*pi/180), 0.5],'ydata',[0.6, 0.6-0.25*cos(LK*pi/180), 0.6-2*0.25*cos(LK*pi/180)],'Color',[0 0.4470 0.7410]);% blue for left
                            %set(app.h5(3),'xdata',[0.45, 0.55],'ydata',[0.6, 0.9],'b');% black for trunk
                            %set(app.h2(1),'xdata',app.timeSet,'ydata',app.percentageSet);
                            %set(app.h2(2),'xdata',app.timeSet,'ydata',app.percentageSet);
                            %set(app.h3(1),'xdata',app.timeSet,'ydata',app.torque_commandSet);
                            %set(app.h3(2),'xdata',app.timeSet,'ydata',app.torqueSet);
                            %set(app.h4(1),'xdata',app.timeSet,'ydata',app.current_commandSet);
                            %set(app.h4(2),'xdata',app.timeSet,'ydata',app.currentSet);
                        end
                        
                    else
                        %flush(app.serialConn);
                    end
                else
                    %flush(app.serialConn);
                end
            else
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.timeSet=0.01:0.01:0.01*app.mat_save_interval;
            app.mat_end=app.mat_save_interval;

            app.angleL_Set=zeros(app.mat_save_interval,1);
            app.angleR_Set=zeros(app.mat_save_interval,1);
            app.current_command_L_Set=zeros(app.mat_save_interval,1);
            app.current_command_R_Set=zeros(app.mat_save_interval,1);
            app.current_L_Set=zeros(app.mat_save_interval,1);
            app.current_R_Set=zeros(app.mat_save_interval,1);
            app.torque_command_L_Set=zeros(app.mat_save_interval,1);
            app.torque_command_R_Set=zeros(app.mat_save_interval,1);
            app.torque_L_Set=zeros(app.mat_save_interval,1);
            app.torque_R_Set=zeros(app.mat_save_interval,1);
            app.lMotorAngle_Set=zeros(app.mat_save_interval,1);
            app.rMotorAngle_Set=zeros(app.mat_save_interval,1);
            app.lMotorSpeed_Set=zeros(app.mat_save_interval,1);
            app.rMotorSpeed_Set=zeros(app.mat_save_interval,1);
            %app.trigger_Set=zeros(app.mat_save_interval,1);
            
            %app.percentageSet=zeros(app.mat_save_interval,1);
            %app.torque_commandSet=zeros(app.mat_save_interval,1);
            %app.torqueSet=zeros(app.mat_save_interval,1);
            %app.current_commandSet=zeros(app.mat_save_interval,1);
            %app.currentSet=zeros(app.mat_save_interval,1);
            %app.LTAVSet=zeros(app.mat_save_interval,1);
            %app.RTAVSet=zeros(app.mat_save_interval,1);
            %app.LSAVSet=zeros(app.mat_save_interval,1);
            %app.RSAVSet=zeros(app.mat_save_interval,1);
            %app.LTSet=zeros(app.mat_save_interval,1);
            %app.RTSet=zeros(app.mat_save_interval,1);
            %app.isStandSet=zeros(app.mat_save_interval,1);
            %app.torque_command_GPSet=zeros(app.mat_save_interval,1);
            serialPorts = instrhwinfo('serial');
            nPorts = length(serialPorts.SerialPorts);
            app.PortDropDown.Items=serialPorts.SerialPorts;
            %             serialPorts = serialportlist('available');
            %             nPorts = length(serialPorts);
            %             app.PortDropDown.Items=serialPorts;
            %
%             disp(size([app.normalizedGaitPhaseList;app.normalizedGaitPhaseList]))
%             disp(size([app.lKneeAngleNormalizedHistory;app.lCommandTorqueNormalizedHistory]))
            app.UIAxes_1.XLim=[0 2];
            app.UIAxes_2.XLim=[0 2];
            app.UIAxes_3.XLim=[0 2];
            app.UIAxes_4.XLim=[0 2];
            %app.UIAxes_5.XLim=[0 100];
            %app.UIAxes_6.XLim=[0 100];
            app.UIAxes_1.YLim=[-40,120];
            app.UIAxes_2.YLim=[-20 20];
            app.UIAxes_3.YLim=[-20 20];
            app.UIAxes_4.YLim=[-20 20];
%             app.UIAxes_5.YLim=[-8 8];
            app.h1=plot(app.UIAxes_1,[0 0; 0 0],[0 0;0 0]);
            app.h2=plot(app.UIAxes_2,[0 0; 0 0],[0 0;0 0]);
            app.h3=plot(app.UIAxes_3,[0 0;0 0],[0 0;0 0]);
            app.h4=plot(app.UIAxes_4,[0 0;0 0],[0 0;0 0]);
%             app.h5=plot(app.UIAxes_5,[0.45 0.55 0.5;0.45 0.55 0.5],[0.1 0.1 0.6;0.6 0.6 0.9],'k');% [x1a,x1b,x1c;x2a,x2b,x2c],[y1a,y1b,y1c;y2a,y2b,y2c]
%             app.h5 = plot(app.UIAxes_5,[app.normalizedGaitPhaseList;app.normalizedGaitPhaseList],[app.lKneeAngleNormalizedHistory;app.lCommandTorqueNormalizedHistory]);
            %app.h5 = plot(app.UIAxes_5,app.normalizedGaitPhaseList,app.lKneeAngleNormalizedHistory);
            %app.h6 = plot(app.UIAxes_6,app.normalizedGaitPhaseList,app.lPowerNormalizedHistory);
            app.wsindex=0;
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.wsindex=1;
            openport=app.PortDropDown.Value;
            if strcmp(app.ConnectButton.Text,'Connect')

                %app.DateLabel.Text=app.BaudRateDropDown.Value;
                obj1 = instrfind('Type', 'serial', 'Port', openport, 'Tag', '');
                if isempty(obj1)
                    if(app.flag==0)
                        app.serialConn = serialport(openport,str2num(app.BaudRateDropDown.Value),'TimeOut',1);
                    end
                    configureCallback(app.serialConn,"byte",app.ble_datalength,@app.serial_data_available);
                    set(app.SaveDataButton, 'Enable', 'On');
                    app.ConnectButton.Text='Disconnect';
                else
                    fclose(obj1);
                    app.serialConn = obj1(1);
                end
            elseif strcmp(app.ConnectButton.Text,'Disconnect')
                app.ConnectButton.Text='Connect';
                try
                    app.flag=1;
                    configureCallback(app.serialConn,"off");
                    delete(instrfindall)
                catch e
                    errordlg(e.message);
                end
            end
        end

        % Close request function: ExoskeletonAppUIFigure
        function ExoskeletonAppUIFigureCloseRequest(app, event)
            try
                configureCallback(app.serialConn,"off");
            catch e
                errordlg(e.message);
            end
            delete(app)
        end

        % Value changed function: PortDropDown
        function PortDropDownValueChanged(app, event)
            serialPorts = instrhwinfo('serial');
            nPorts = length(serialPorts.SerialPorts);
            app.PortDropDown.Items=serialPorts.SerialPorts;
            %             serialPorts = serialportlist('available');
            %             nPorts = length(serialPorts);
            %             app.PortDropDown.Items=serialPorts;
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            if strcmp(app.SaveDataButton.Text,'Save Data')
                app.SaveDataButton.Text='Stop Record';
                app.isSaveData=1;
                app.mat_ini = 1;
                app.mat_end = 200;
                data=zeros(1,app.num_variables);
                filename=['data_', datestr(now, 'yyyy_mm_dd_HH_MM_SS'), '.mat'];
                save(filename, 'data', '-v7.3');
                app.m = matfile(filename, 'Writable', true);
            else
                app.SaveDataButton.Text='Save Data';
                app.isSaveData=0;
            end
            %             str=['data(1,:)=[2 3 4]'];
            %             evalin('base', str);
            %
            %             assignin('base','X',-1);
            %             str=['X(' num2str(3) ':' num2str(5) ')=-1' ]
            %             evalin('base',str);
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            value = app.ModeDropDown.Value;
            if strcmp(value,"Constant Current")
                mode = 1;
            elseif strcmp(value,"Sine Wave Current")
                mode = 2;
            elseif strcmp(value,"Gravity Compensation - Pos")
                mode = 3;
            elseif strcmp(value,"Gravity Compensation - Vel")
                mode = 4;
            elseif strcmp(value,"Gravity Compensation - integ Vel")
                mode = 5;
            elseif strcmp(value,"Gravity Compensation - RX integ Vel")
                mode = 6;
            elseif strcmp(value,"Gravity Compensation - LX integ Vel")
                mode = 7;
            else
                mode = 100; % "Zero Current"
            end
            a=[165 90 app.rs232_datalength 1 mode 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
        end

        % Value changed function: STOPButton
        function STOPButtonValueChanged(app, event)
            value = app.STOPButton.Value;
            if value
                app.STOPButton.BackgroundColor = 'g';
                app.STOPButton.Text = "START";
            else
                app.STOPButton.BackgroundColor = 'r';
                app.STOPButton.Text = "STOP";
            end
            a=[165 90 app.rs232_datalength 0 value 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            a=[165 90 app.rs232_datalength 10 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
        end

        % Value changed function: StartLoggingButton
        function onDataLoggingButtonClicked(app, event)
            isLogging = app.StartLoggingButton.Value;
            if isLogging == 1
                app.StartLoggingButton.Text = "Stop Logging";
            else
                app.StartLoggingButton.Text = "Start Logging";
            end

            taskName = app.TaskDropDown.Value;
            if strcmp(taskName,'A') == 1
                taskIdx = 1;
            elseif strcmp(taskName,'B') == 1
                taskIdx = 2;
            end

            conditionName = app.ConditionDropDown.Value;
            if strcmp(conditionName,'Baseline') == 1
                conditionIdx = 1;
            elseif strcmp(conditionName,'Sham') == 1
                conditionIdx = 2;
            elseif strcmp(conditionName,'Powered') == 1
                conditionIdx = 3;
            end
            trialIdx = app.TrialSpinner.Value;
            disp(['taskIdx = ',num2str(taskIdx),'conditionIdx = ',num2str(conditionIdx),', trialIdx = ',num2str(trialIdx),', isLogging = ',num2str(isLogging)])
            a=[165 90 app.rs232_datalength 20 taskIdx conditionIdx trialIdx isLogging 0 0 0 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
            
        end

        % Value changed function: LeftGainSpinner
        function LeftGainSpinnerValueChanged(app, event)
            value = app.LeftGainSpinner.Value;
            limitation = 5;
            value = max(value,-limitation);
            value = min(value,+limitation);
            bytearray=typecast(int16(value*1000), 'uint8');
            a=[165 90 app.rs232_datalength 2 bytearray(1) bytearray(2) 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
        end

        % Value changed function: RightGainSpinner
        function RightGainSpinnerValueChanged(app, event)
            value = app.RightGainSpinner.Value;
            limitation = 5;
            value = max(value,-limitation);
            value = min(value,+limitation);
            bytearray=typecast(int16(value*1000), 'uint8');
            a=[165 90 app.rs232_datalength 3 bytearray(1) bytearray(2) 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
        end

        % Value changed function: CommonGainSpinner
        function CommonGainSpinnerValueChanged(app, event)
            value = app.CommonGainSpinner.Value;
            limitation = 5;
            value = max(value,-limitation);
            value = min(value,+limitation);
            bytearray=typecast(int16(value*1000), 'uint8');
            a=[165 90 app.rs232_datalength 4 bytearray(1) bytearray(2) 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
        end

        % Value changed function: HeightmEditField
        function HeightmEditFieldValueChanged(app, event)
            value = app.HeightmEditField.Value;
            min_limitation = 1.65;
            max_limitation = 1.95;
            value = max(value,min_limitation);
            value = min(value,max_limitation);
            bytearray=typecast(int16(value*100), 'uint8');
            a=[165 90 app.rs232_datalength 5 bytearray(1) bytearray(2) 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
        end

        % Value changed function: WeightkgEditField
        function WeightkgEditFieldValueChanged(app, event)
            value = app.WeightkgEditField.Value;
            min_limitation = 50;
            max_limitation = 150;
            value = max(value,min_limitation);
            value = min(value,max_limitation);
            bytearray=typecast(int16(value*10), 'uint8');
            a=[165 90 app.rs232_datalength 6 bytearray(1) bytearray(2) 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
        end

        % Selection changed function: SexButtonGroup
        function SexButtonGroupSelectionChanged(app, event)
            %selectedButton = app.SexButtonGroup.SelectedObject;
            if app.FButton.Value == 1
                value = 1;
            elseif app.MButton.Value == 1 
                value = 0;
            end
            a=[165 90 app.rs232_datalength 7 value 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
        end

        % Value changed function: AngleThresholdSpinner
        function AngleThresholdSpinnerValueChanged(app, event)
            value = app.AngleThresholdSpinner.Value;
            value = max(value,0);
            value = min(value,90);
            bytearray=typecast(int16(value*10), 'uint8');
            a=[165 90 app.rs232_datalength 8 bytearray(1) bytearray(2) 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
        end

        % Value changed function: VelocityThresholdSpinner
        function VelocityThresholdSpinnerValueChanged(app, event)
            value = app.VelocityThresholdSpinner.Value;
            value = max(value,0);
            value = min(value,200);
            bytearray=typecast(int16(value*10), 'uint8');
            a=[165 90 app.rs232_datalength 9 bytearray(1) bytearray(2) 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            write(app.serialConn,a,"uint8");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExoskeletonAppUIFigure = uifigure('Visible', 'off');
            app.ExoskeletonAppUIFigure.Name = 'Exoskeleton App';
            app.ExoskeletonAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @ExoskeletonAppUIFigureCloseRequest, true);
            app.UIAxes_2 = uiaxes(app.ExoskeletonAppUIFigure);
            title(app.UIAxes_2, 'Current command (left & right)')
            xlabel(app.UIAxes_2, 'Time [s]')
            ylabel(app.UIAxes_2, '[A]')
            app.UIAxes_4 = uiaxes(app.ExoskeletonAppUIFigure);
            title(app.UIAxes_4, 'Right Torque (command & actual)')
            xlabel(app.UIAxes_4, 'Time [s]')
            ylabel(app.UIAxes_4, '[Nm]')
            app.UIAxes_1 = uiaxes(app.ExoskeletonAppUIFigure);
            title(app.UIAxes_1, 'Angle')
            xlabel(app.UIAxes_1, 'Time [s]')
            ylabel(app.UIAxes_1, '[Deg]')
            app.UIAxes_3 = uiaxes(app.ExoskeletonAppUIFigure);
            title(app.UIAxes_3, 'Left Torque (command & actual)')
            xlabel(app.UIAxes_3, 'Time [s]')
            ylabel(app.UIAxes_3, '[Nm]')
            app.ModeDropDownLabel = uilabel(app.ExoskeletonAppUIFigure);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.ExoskeletonAppUIFigure);
            app.ModeDropDown.Items = {'Constant Current', 'Sine Wave Current', 'Gravity Compensation - Pos', 'Gravity Compensation - Vel', 'Gravity Compensation - integ Vel', 'Gravity Compensation - RX integ Vel', 'Gravity Compensation - LX integ Vel'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'Gravity Compensation - Pos';
            app.ConnectButton = uibutton(app.ExoskeletonAppUIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.BaudRateDropDownLabel = uilabel(app.ExoskeletonAppUIFigure);
            app.BaudRateDropDownLabel.Text = 'Baud Rate';
            app.BaudRateDropDown = uidropdown(app.ExoskeletonAppUIFigure);
            app.BaudRateDropDown.Items = {'115200', '230400', '460800'};
            app.BaudRateDropDown.Value = '115200';
            app.PortDropDownLabel = uilabel(app.ExoskeletonAppUIFigure);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.ExoskeletonAppUIFigure);
            app.PortDropDown.Items = {};
            app.PortDropDown.ValueChangedFcn = createCallbackFcn(app, @PortDropDownValueChanged, true);
            app.PortDropDown.Value = {};
            app.SaveDataButton = uibutton(app.ExoskeletonAppUIFigure, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.DateLabel = uilabel(app.ExoskeletonAppUIFigure);
            app.DateLabel.Text = 'Date';
            app.TimeLabel = uilabel(app.ExoskeletonAppUIFigure);
            app.TimeLabel.Text = 'Time';
            app.STOPButton = uibutton(app.ExoskeletonAppUIFigure, 'state');
            app.STOPButton.ValueChangedFcn = createCallbackFcn(app, @STOPButtonValueChanged, true);
            app.STOPButton.Text = 'STOP';
            app.TabGroup = uitabgroup(app.ExoskeletonAppUIFigure);
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.WeightkgEditFieldLabel = uilabel(app.ParametersTab);
            app.WeightkgEditFieldLabel.Text = 'Weight [kg]';
            app.WeightkgEditField = uieditfield(app.ParametersTab, 'numeric');
            app.WeightkgEditField.ValueChangedFcn = createCallbackFcn(app, @WeightkgEditFieldValueChanged, true);
            app.WeightkgEditField.Value = 70;
            app.HeightmEditFieldLabel = uilabel(app.ParametersTab);
            app.HeightmEditFieldLabel.Text = 'Height [m]';
            app.HeightmEditField = uieditfield(app.ParametersTab, 'numeric');
            app.HeightmEditField.ValueChangedFcn = createCallbackFcn(app, @HeightmEditFieldValueChanged, true);
            app.HeightmEditField.Value = 1.75;
            app.SexButtonGroup = uibuttongroup(app.ParametersTab);
            app.SexButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SexButtonGroupSelectionChanged, true);
            app.SexButtonGroup.Title = 'Sex';
            app.MButton = uiradiobutton(app.SexButtonGroup);
            app.MButton.Text = 'M';
            app.MButton.Value = true;
            app.FButton = uiradiobutton(app.SexButtonGroup);
            app.FButton.Text = 'F';
            app.USERLabel = uilabel(app.ParametersTab);
            app.USERLabel.Text = 'USER';
            app.CONTROLLabel = uilabel(app.ParametersTab);
            app.CONTROLLabel.Text = 'CONTROL';
            app.AngleThresholdSpinnerLabel = uilabel(app.ParametersTab);
            app.AngleThresholdSpinnerLabel.Text = 'Angle Threshold';
            app.AngleThresholdSpinner = uispinner(app.ParametersTab);
            app.AngleThresholdSpinner.Limits = [0 90];
            app.AngleThresholdSpinner.ValueDisplayFormat = '%.0f';
            app.AngleThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @AngleThresholdSpinnerValueChanged, true);
            app.AngleThresholdSpinner.Value = 25;
            app.VelocityThresholdSpinnerLabel = uilabel(app.ParametersTab);
            app.VelocityThresholdSpinnerLabel.Text = 'Velocity Threshold';
            app.VelocityThresholdSpinner = uispinner(app.ParametersTab);
            app.VelocityThresholdSpinner.Limits = [0 200];
            app.VelocityThresholdSpinner.ValueDisplayFormat = '%.0f';
            app.VelocityThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @VelocityThresholdSpinnerValueChanged, true);
            app.VelocityThresholdSpinner.Value = 25;
            app.LeftGainSpinnerLabel = uilabel(app.ParametersTab);
            app.LeftGainSpinnerLabel.Text = 'Left Gain';
            app.LeftGainSpinner = uispinner(app.ParametersTab);
            app.LeftGainSpinner.Step = 0.1;
            app.LeftGainSpinner.Limits = [-1 2];
            app.LeftGainSpinner.ValueDisplayFormat = '%.1f';
            app.LeftGainSpinner.ValueChangedFcn = createCallbackFcn(app, @LeftGainSpinnerValueChanged, true);
            app.LeftGainSpinner.Value = 1;
            app.RightGainSpinnerLabel = uilabel(app.ParametersTab);
            app.RightGainSpinnerLabel.Text = 'Right Gain';
            app.RightGainSpinner = uispinner(app.ParametersTab);
            app.RightGainSpinner.Step = 0.1;
            app.RightGainSpinner.Limits = [-1 2];
            app.RightGainSpinner.ValueDisplayFormat = '%.1f';
            app.RightGainSpinner.ValueChangedFcn = createCallbackFcn(app, @RightGainSpinnerValueChanged, true);
            app.RightGainSpinner.Value = 1;
            app.CommonGainSpinnerLabel = uilabel(app.ParametersTab);
            app.CommonGainSpinnerLabel.Text = 'Common Gain';
            app.CommonGainSpinner = uispinner(app.ParametersTab);
            app.CommonGainSpinner.Step = 0.05;
            app.CommonGainSpinner.Limits = [0 1];
            app.CommonGainSpinner.ValueDisplayFormat = '%.2f';
            app.CommonGainSpinner.ValueChangedFcn = createCallbackFcn(app, @CommonGainSpinnerValueChanged, true);
            app.CommonGainSpinner.Value = 0.5;
            app.RESETButton = uibutton(app.ExoskeletonAppUIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.ConditionDropDownLabel = uilabel(app.ExoskeletonAppUIFigure);
            app.ConditionDropDownLabel.Text = 'Condition';
            app.ConditionDropDown = uidropdown(app.ExoskeletonAppUIFigure);
            app.ConditionDropDown.Items = {'Baseline', 'Sham', 'Powered'};
            app.ConditionDropDown.Value = 'Baseline';
            app.TrialSpinnerLabel = uilabel(app.ExoskeletonAppUIFigure);
            app.TrialSpinnerLabel.Text = 'Trial #';
            app.TrialSpinner = uispinner(app.ExoskeletonAppUIFigure);
            app.TrialSpinner.Limits = [1 Inf];
            app.TrialSpinner.RoundFractionalValues = 'on';
            app.TrialSpinner.Value = 1;
            app.StartLoggingButton = uibutton(app.ExoskeletonAppUIFigure, 'state');
            app.StartLoggingButton.ValueChangedFcn = createCallbackFcn(app, @onDataLoggingButtonClicked, true);
            app.StartLoggingButton.Text = 'Start Logging';
            app.TaskDropDownLabel = uilabel(app.ExoskeletonAppUIFigure);
            app.TaskDropDownLabel.Text = 'Task';
            app.TaskDropDown = uidropdown(app.ExoskeletonAppUIFigure);
            app.TaskDropDown.Items = {'A', 'B'};
            app.TaskDropDown.Value = 'A';
            % Show the figure after all components are created
            app.ExoskeletonAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Exoskeleton_User_Interface_32data_Junxi_20220812

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExoskeletonAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExoskeletonAppUIFigure)
        end
    end
end